classdef finalproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GuessButton              matlab.ui.control.Button
        Label_7                  matlab.ui.control.Label
        loadnetworkButton        matlab.ui.control.Button
        Label_6                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        AccurancyLabel_3         matlab.ui.control.Label
        PredictionResultLabel_3  matlab.ui.control.Label
        AccurancyLabel_2         matlab.ui.control.Label
        PredictionResultLabel_2  matlab.ui.control.Label
        AccurancyLabel           matlab.ui.control.Label
        PredictionResultLabel    matlab.ui.control.Label
        AlexnetLabel             matlab.ui.control.Label
        GooglelanetLabel         matlab.ui.control.Label
        Renet50ResultLabel       matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        importpictureButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        ImageFile;
        imds;
testSet;
testImage;
testImage1;
testImage2;
testLabel;
net;
classifier;
ranet;
googlenet;
alexnet;
classNames;
    end
    
    methods (Access = private)
        
function data = customreader(filename)
onState = warning('off', 'backtrace');
c = onCleanup(@() warning(onState));
data = imread(filename);
data = data(:,:,min(1:3, end)); 
data = imresize(data, [224 224 3]);
data2 = imresize(data,[227 227 3]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: importpictureButton
        function importpictureButtonPushed(app, event)
% import image
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           app.ImageFile = imread(fullname);
           app.Image.ImageSource = app.ImageFile;
app.testImage1 = imresize(app.ImageFile, [224 224]);
app.testImage2 = imresize(app.ImageFile, [227 227]);
        end

        % Button pushed function: loadnetworkButton
        function loadnetworkButtonPushed(app, event)
% Location 
netfolder = uigetdir;
matfiles = dir(fullfile(netfolder,'*.mat')) ; 
N = length(matfiles) ; 
for i = 1:N
    load(matfiles(i).name)
end
app.Label_7.Text='on loading';
app.ranet = load("nettrain.mat");
app.ranet=app.ranet.nettrain;
app.Label_7.Text='Net 1 Completed';
app.googlenet=load("nettrain2.mat");
app.googlenet=app.googlenet.nettrain2;
app.Label_7.Text='Net 2 Completed';
app.alexnet=load("nettrain3.mat");
app.alexnet=app.alexnet.nettrain3;
app.Label_7.Text='Net 3 Completed';
app.classNames = app.ranet.Layers(end).ClassNames;

        end

        % Button pushed function: GuessButton
        function GuessButtonPushed(app, event)
 [YPredn1, Scoresn1] = classify(app.ranet, app.testImage1);
 app.Label.Text=string(YPredn1);
 app.Label_2.Text=num2str(100*Scoresn1(app.classNames == YPredn1),3) + "%";
 [YPredn2, Scoresn2] = classify(app.googlenet, app.testImage1);
 app.Label_3.Text=string(YPredn2);
 app.Label_4.Text=num2str(100*Scoresn2(app.classNames == YPredn2),3) + "%";
 [YPredn3, Scoresn3] = classify(app.alexnet, app.testImage2);
 app.Label_5.Text=string(YPredn3);
 app.Label_6.Text=num2str(100*Scoresn3(app.classNames == YPredn3),3) + "%";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.importpictureButton = uibutton(app.UIFigure, 'push');
            app.importpictureButton.ButtonPushedFcn = createCallbackFcn(app, @importpictureButtonPushed, true);
            app.importpictureButton.Text = 'import picture';
            app.Image = uiimage(app.UIFigure);
            app.Renet50ResultLabel = uilabel(app.UIFigure);
            app.Renet50ResultLabel.Text = 'Renet50 Result';
            app.GooglelanetLabel = uilabel(app.UIFigure);
            app.GooglelanetLabel.Text = 'Googlelanet';
            app.AlexnetLabel = uilabel(app.UIFigure);
            app.AlexnetLabel.Text = 'Alexnet';
            app.PredictionResultLabel = uilabel(app.UIFigure);
            app.PredictionResultLabel.Text = 'Prediction Result';
            app.AccurancyLabel = uilabel(app.UIFigure);
            app.AccurancyLabel.Text = 'Accurancy';
            app.PredictionResultLabel_2 = uilabel(app.UIFigure);
            app.PredictionResultLabel_2.Text = 'Prediction Result';
            app.AccurancyLabel_2 = uilabel(app.UIFigure);
            app.AccurancyLabel_2.Text = 'Accurancy';
            app.PredictionResultLabel_3 = uilabel(app.UIFigure);
            app.PredictionResultLabel_3.Text = 'Prediction Result';
            app.AccurancyLabel_3 = uilabel(app.UIFigure);
            app.AccurancyLabel_3.Text = 'Accurancy';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.loadnetworkButton = uibutton(app.UIFigure, 'push');
            app.loadnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @loadnetworkButtonPushed, true);
            app.loadnetworkButton.Text = 'load network';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.GuessButton = uibutton(app.UIFigure, 'push');
            app.GuessButton.ButtonPushedFcn = createCallbackFcn(app, @GuessButtonPushed, true);
            app.GuessButton.Text = 'Guess';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalproject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end